classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OPTIMALELabel          matlab.ui.control.Label
        InicijalizacijaButton  matlab.ui.control.Button
        OptimalaDropDown       matlab.ui.control.DropDown
        OptimalaDropDownLabel  matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        StartButton            matlab.ui.control.Button
        ExitButton             matlab.ui.control.Button
        TextArea_2             matlab.ui.control.TextArea
        TextArea_3             matlab.ui.control.TextArea
        TextArea_4             matlab.ui.control.TextArea
        GainKnobLabel          matlab.ui.control.Label
        GainKnob               matlab.ui.control.Knob
        StopButton             matlab.ui.control.Button
        StopButton_2           matlab.ui.control.Button
        ExitButton_2           matlab.ui.control.Button
    end


    methods (Access = private)

        % Callback function: InicijalizacijaButton, TextArea
        function InicijalizacijaButtonPushed(app, event)
                global fileReader;
                 global deviceWriter;
                global A;
                Fn = 44100/2;   % Stopband Frequency (Normalised)
                i=1;
                % fc = [200,250,315,400,500,630,800,1000,1250,1600,2000,2500,3150,4000,5000,6300,8024,10000,12500];
                Wn = [178,224,282,355,447,562,708,891,1120,1410, 1780,2240,2820,3550,4470,5620,7080,8910,11200,14100];
                  A = zeros(4*19+8,6);
                              [z,p,k] = butter(4,[0.001/Fn Wn(1)/Fn],'bandpass');  % Butterworth filter
                    [sos] = zp2sos(z,p,k);          % Convert to SOS form
                    A(1:4,1:6)= sos; 
                    j=4;
                for i=1:19
                    [z,p,k] = butter(4,[Wn(i)/Fn Wn(i+1)/Fn],'bandpass');  % Butterworth filter
                    [sos] = zp2sos(z,p,k);          % Convert to SOS form
                    A(j+1:j+4,1:6)= sos;
                    j = j+4;
                end
                [z,p,k] = butter(4,[Wn(20)/Fn 0.999],'bandpass');  % Butterworth filter
                    [sos] = zp2sos(z,p,k);          % Convert to SOS form
                    A(j+1:j+4,1:6)= sos;


                    app.TextArea.Value = 'Gotova inicijalizacija';
%                 fwrite(s,'0','char');
%                 for i=1:19
%                         Y = cell2mat(C(I));
%                         for j=1:4
%                             for k=1:6
%                                 fwrite(s,Y(j,k),'int16');
%                             end
%                         end
%                 end                  
%                  fread(s);
%                     fclose(s);
%                     global X;
%                 fileID = fopen('coeff.txt','w');
%                  for i=1:21
%                      y = cell2mat(C(i));
%                      for j=1:4
%                        for k=1:6
%                            X(j,k)=y(j,k);
%                            fprintf(fileID,'%d,',X(j,k));
%                        end
%                      end
%                  end
%                 fclose(fileID);


        end

        % Callback function: GainKnob, OptimalaDropDown, TextArea_3
        function OptimalaDropDownValueChanged(app, event)
            value = app.OptimalaDropDown.Value;
                global a;
            global out;
            global offset;
            global gain;
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                 for i=1:length(offset)*2
%                     if(i < 5)
%                         fwrite(s,offset(i),'uint8');
%                     else
%                         fwrite(s,gain(i),'uint8');
%                     end
%                 end        
                    %             end
               app.TextArea_3.Value = app.OptimalaDropDown.Value;
               
            if(value == 'A')
                offset = [6,7,8,9];
                gain = app.GainKnob.Value;
                    
            end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                       
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                       
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                         
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                         
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                       
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end


        end

        % Callback function: ExitButton_2, StartButton, StopButton, 
        % StopButton_2, TextArea_2
        function StartButtonPushed(app, event)
           global fileReader;
           global deviceWriter;
           global A;
           global offset;
           global gain;
           frameLength = 4096;
           fileReader = audioDeviceReader('SamplesPerFrame',frameLength);
           deviceWriter = audioDeviceWriter( ...
                        'SampleRate',fileReader.SampleRate);
            out = 0;
            i=1;
            while (i~=0)
                 app.TextArea_2.Value = 'processing..';
                audio = record(fileReader);
                j=1;
                k=1;
                 for i=1:20
                      if(i==offset(j))
                         a  = 10^(gain/20).*sosfilt(A(k:k+3,1:6),audio,frameLength); 
                         if(j<4)
                             j = j+1;
                         end
                      else
                         a  = sosfilt(A(k:k+3,1:6),audio,frameLength);
                      end
                      out = out + a;
                      k= k+4;
                 end
                i=1;
                step(deviceWriter, out);
               
                out=0;
                a=0;
                
                if(app.ExitButton_2.isvalid == 'true')
                    release(fileReader)                         %<---
                    release(deviceWriter) 
                    i=0;
                end;
            end


            i=1;
            while(i~=0)
                audio = record(fileReader);
                app.TextArea_4.Value = 'ušlo';
                step(deviceWriter, audio);
                if(app.ExitButton_2.isvalid == 'true')
                    release(fileReader)                         %<---
                    release(deviceWriter) 
                    i=0;
                end;
            end 


        end

        % Value changed function: TextArea_4
        function StopButtonPushed(app, event)
            global fileReader;
           global deviceWriter;
           if(isLocked(fileReader) || isLocked(deviceWriter))
               release(fileReader)                         %<---
               release(deviceWriter) 
           end;
            audio = record(fileReader);
                app.TextArea_4.Value = 'ušlo';
                app.StopButton.BackgroundColor = 'g';
                step(deviceWriter, audio);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            global fileReader;
            global deviceWriter;
            app.ExitButton.BackgroundColor = 'g';
            release(fileReader)                         %<---
            release(deviceWriter) 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OPTIMALELabel = uilabel(app.UIFigure);
            app.OPTIMALELabel.Text = 'OPTIMALE';
            app.InicijalizacijaButton = uibutton(app.UIFigure, 'push');
            app.InicijalizacijaButton.ButtonPushedFcn = createCallbackFcn(app, @InicijalizacijaButtonPushed, true);
            app.InicijalizacijaButton.Text = 'Inicijalizacija';
            app.OptimalaDropDown = uidropdown(app.UIFigure);
            app.OptimalaDropDown.Items = {'Odaberi', 'A', 'E', 'I', 'O', ''};
            app.OptimalaDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimalaDropDownValueChanged, true);
            app.OptimalaDropDown.Value = 'Odaberi';
            app.OptimalaDropDownLabel = uilabel(app.UIFigure);
            app.OptimalaDropDownLabel.Text = 'Optimala';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @InicijalizacijaButtonPushed, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @OptimalaDropDownValueChanged, true);
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.ValueChangedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.GainKnobLabel = uilabel(app.UIFigure);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.UIFigure, 'continuous');
            app.GainKnob.Limits = [0 20];
            app.GainKnob.ValueChangingFcn = createCallbackFcn(app, @OptimalaDropDownValueChanged, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StopButton_2 = uibutton(app.UIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StopButton_2.Text = 'Stop';
            app.ExitButton_2 = uibutton(app.UIFigure, 'push');
            app.ExitButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.ExitButton_2.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end