classdef app_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OPTIMALELabel          matlab.ui.control.Label
        InicijalizacijaButton  matlab.ui.control.Button
        OptimalaDropDown       matlab.ui.control.DropDown
        OptimalaDropDownLabel  matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        GainKnobLabel          matlab.ui.control.Label
        GainKnob               matlab.ui.control.Knob
        NotesTextAreaLabel     matlab.ui.control.Label
        NotesTextArea          matlab.ui.control.TextArea
        ButtonGroup            matlab.ui.container.ButtonGroup
        StartButton            matlab.ui.control.ToggleButton
        FilterButton           matlab.ui.control.ToggleButton
        ExitButton             matlab.ui.control.ToggleButton
    end


    methods (Access = private)

        % Callback function: InicijalizacijaButton, TextArea
        function InicijalizacijaButtonPushed(app, event)
                global fileReader;
                global deviceWriter;
                 
                global A;
                Fn = 44100/2;   % Stopband Frequency (Normalised)
                i=1;
                % fc = [200,250,315,400,500,630,800,1000,1250,1600,2000,2500,3150,4000,5000,6300,8024,10000,12500];
                Wn = [178,224,282,355,447,562,708,891,1120,1410, 1780,2240,2820,3550,4470,5620,7080,8910,11200,14100];
                  A = zeros(4*19+8,6);
                              [z,p,k] = butter(4,[0.001/Fn Wn(1)/Fn],'bandpass');  % Butterworth filter
                    [sos] = zp2sos(z,p,k);          % Convert to SOS form
                    A(1:4,1:6)= sos; 
                    j=4;
                for i=1:19
                    [z,p,k] = butter(4,[Wn(i)/Fn Wn(i+1)/Fn],'bandpass');  % Butterworth filter
                    [sos] = zp2sos(z,p,k);          % Convert to SOS form
                    A(j+1:j+4,1:6)= sos;
                    j = j+4;
                end
                [z,p,k] = butter(4,[Wn(20)/Fn 0.999],'bandpass');  % Butterworth filter
                    [sos] = zp2sos(z,p,k);          % Convert to SOS form
                    A(j+1:j+4,1:6)= sos;


                    app.TextArea.Value = 'Gotova inicijalizacija';
%                 fwrite(s,'0','char');
%                 for i=1:19
%                         Y = cell2mat(C(I));
%                         for j=1:4
%                             for k=1:6
%                                 fwrite(s,Y(j,k),'int16');
%                             end
%                         end
%                 end                  
%                  fread(s);
%                     fclose(s);
%                     global X;
%                 fileID = fopen('coeff.txt','w');
%                  for i=1:21
%                      y = cell2mat(C(i));
%                      for j=1:4
%                        for k=1:6
%                            X(j,k)=y(j,k);
%                            fprintf(fileID,'%d,',X(j,k));
%                        end
%                      end
%                  end
%                 fclose(fileID);
     
                 frameLength = 1024;
           fileReader = audioDeviceReader('SamplesPerFrame',frameLength);
           deviceWriter = audioDeviceWriter( ...
                        'SampleRate',fileReader.SampleRate);


        end

        % Value changed function: OptimalaDropDown
        function OptimalaDropDownValueChanged(app, event)
            value = app.OptimalaDropDown.Value;

           global A;
           global fileReader;
           global deviceWriter;
           global i;
           global gain;
           global offset;
           global frameLength;


%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                 for i=1:length(offset)*2
%                     if(i < 5)
%                         fwrite(s,offset(i),'uint8');
%                     else
%                         fwrite(s,gain(i),'uint8');
%                     end
%                 end        
                    %             end
               app.NotesTextArea.Value = app.OptimalaDropDown.Value;
               
            if(value == 'A')
                offset = [6,7,8,9];
                gain = app.GainKnob.Value;
                    
            end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                       
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                       
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                         
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                         
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                       
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
%             if(value == 'A')
%                 offset = [6,7,8,9];
%                 gain = [3,3,3,3];
%                        
%             end
     
        end

        % Callback function
        function KreniButtonPushed(app, event)
             global fileReader;
             global deviceWriter;
             global i;
             global gain;
             global offset;
             global A;
             global frameLength;
             out=0;
             app.NotesTextArea.Value = strcat('Gain: ', num2str(gain),' dB');
             while true
                    drawnow;
                    j=1;
                    k=1;
                    audio = record(fileReader);
                    for i=1:20
                          if(i==offset(j))
                             a  = 10^(app.GainKnob.Value/20).*sosfilt(A(k:k+3,1:6),audio,frameLength); 
                             if(j<4)
                                 j = j+1;
                             end
                          else
                             a  = sosfilt(A(k:k+3,1:6),audio,frameLength);
                          end
                          out = out + a;
                          k= k+4;
                          
                    end
                          step(deviceWriter, audio);
                          out=0;
                          a=0;
                   stop_state = app.StaniButton.BusyAction;
                    if stop_state
                        break;
                    end
                    stop_state = app.PaniButton.BusyAction;
                    if stop_state
                        break;
                    end
             end
        end

        % Callback function
        function StaniButtonPushed(app, event)
             global fileReader;
             global deviceWriter;
             while true
                 drawnow;
                 app.NotesTextArea.Value = '\nplain sound..';
                 audio = record(fileReader);
                 step(deviceWriter, audio);
                 stop_state = app.KreniButton.BusyAction;
                    if stop_state
                        break;
                    end
                    stop_state = app.PaniButton.BusyAction;
                    if stop_state
                        break;
                    end
             end
        end

        % Callback function
        function PaniButtonPushed(app, event)
              global i;
              global fileReader;
              global deviceWriter;
              while true
                  drawnow;
                  app.NotesTextArea.Value = '\nshut down applications..';
                    release(fileReader)                         %<---
                    release(deviceWriter) 
                    

              end
              app.delete;  
        end

        % Callback function: ButtonGroup, GainKnob, GainKnob, 
        % NotesTextArea
        function ButtonGroupSelectionChanged(app, event)
           global fileReader;
             global deviceWriter;
             global i;
             global gain;
             global offset;
             global A;
             global frameLength;
             out=0;
            
            while (app.StartButton.Value ==  true)
                pause(0);
                 app.NotesTextArea.Value = '\nplain sound..';
                 app.NotesTextArea.Value = strcat('Gain: ', num2str(gain),' dB');
                 audio = record(fileReader);
                 step(deviceWriter, audio);
                  if(app.FilterButton.Value == true)
                      break;
                  end
            end
            
            while(app.FilterButton.Value == true)
                  audio = record(fileReader);
                pause(0);
                   app.NotesTextArea.Value = 'filtering..';
                   app.NotesTextArea.Value = strcat('Gain: ', num2str(gain),' dB');
                   j=1;
                   k=1;
                   
                   for i=1:20
                    if(i==offset(j))
                        a  = 10^(gain/20).*sosfilt(A(k:k+3,1:6),audio,frameLength); 
                        
                        if(j<length(offset))
                           j = j+1;
                        end
                    else
                        a  = sosfilt(A(k:k+3,1:6),audio,frameLength);
                    end
                    
                    out = out + a;
                    k= k+4;    
                   end
                i = 1;
                step(deviceWriter, out);
                out=0;
                a=0;
            end  
               
            if(app.ExitButton.Value ==  true)
                pause(0);
                  app.NotesTextArea.Value = '\nshut down applications..';
                  release(fileReader)                         %<---
                  release(deviceWriter) 
                  app.delete;
            end             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OPTIMALELabel = uilabel(app.UIFigure);
            app.OPTIMALELabel.Text = 'OPTIMALE';
            app.InicijalizacijaButton = uibutton(app.UIFigure, 'push');
            app.InicijalizacijaButton.ButtonPushedFcn = createCallbackFcn(app, @InicijalizacijaButtonPushed, true);
            app.InicijalizacijaButton.Text = 'Inicijalizacija';
            app.OptimalaDropDown = uidropdown(app.UIFigure);
            app.OptimalaDropDown.Items = {'Odaberi', 'A', 'E', 'I', 'O', ''};
            app.OptimalaDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimalaDropDownValueChanged, true);
            app.OptimalaDropDown.Value = 'Odaberi';
            app.OptimalaDropDownLabel = uilabel(app.UIFigure);
            app.OptimalaDropDownLabel.Text = 'Optimala';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @InicijalizacijaButtonPushed, true);
            app.GainKnobLabel = uilabel(app.UIFigure);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.UIFigure, 'continuous');
            app.GainKnob.Limits = [0 20];
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GainKnob.ValueChangingFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.NotesTextArea.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NotesTextArea.Editable = 'off';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.StartButton = uitogglebutton(app.ButtonGroup);
            app.StartButton.Text = 'Start';
            app.StartButton.Value = true;
            app.FilterButton = uitogglebutton(app.ButtonGroup);
            app.FilterButton.Text = 'Filter';
            app.ExitButton = uitogglebutton(app.ButtonGroup);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end